classdef HUD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WARNINGLamp           matlab.ui.control.Lamp
        WARNINGLampLabel      matlab.ui.control.Label
        PlaySimulationButton  matlab.ui.control.Button
        CompassAxes           matlab.ui.control.UIAxes
        UIOxygenAxes          matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
        function results = videoRead(app)
            filename = 'myvideo.mp4';
            v = VideoReader(filename);
            while hasFrame(v)
             imshow(getFrame(v), 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIOxygenAxes.Title.Color = 'w';  % Change title font color to white
            % since apparently this isn't an option in design viewer, so we
            % have to manually do it  
            
            % manually changing some compass features
            app.CompassAxes.XColor = 'none';
            app.CompassAxes.YColor = 'none';
            app.CompassAxes.Color = 'none'; % Transparent background
            app.CompassAxes.Box = 'off'; % Remove box outline
            axis(app.CompassAxes, 'equal');
            hold(app.CompassAxes, 'on');
            
            % static compass rose
            theta = linspace(0, 2*pi, 100);
            x = cos(theta);
            y = sin(theta);
            plot(app.CompassAxes, x, y, 'w'); % circle outline for compass
            

            text(app.CompassAxes, 0, 1.2, 'N', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 1.2, 0, 'E', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, 0, -1.2, 'S', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            text(app.CompassAxes, -1.2, 0, 'W', 'HorizontalAlignment', 'center', 'Color', 'w','FontName', 'Consolas');
            
   
        end

        % Button pushed function: PlaySimulationButton
        function PlaySimulationButtonPushed(app, event)
            % When play simulation button is pushed, read in HUDFootage and
            % display it within our figure (UIAxes)
            filename = 'data/HUDFootage.mp4';
            v = VideoReader(filename);
            frame = readFrame(v);
            im_frame = imshow(frame, 'Parent', app.UIAxes);
           
            % Initialize dynamic heading pointer
            headingAngle = 90; % Initial heading, update dynamically
            xPointer = [0, cosd(headingAngle)];
            yPointer = [0, sind(headingAngle)];
            compassPointer = plot(app.CompassAxes, xPointer, yPointer, 'r', 'LineWidth', 2);


            % load oxygen data
            oxygenData = load('data/displayData.csv');
            oxygenData = oxygenData(2,:)'; % column vector made from row 2 of csv
            
            % Prepare oxygen bar
            hold(app.UIOxygenAxes, 'on');
            oxygenBar = rectangle(app.UIOxygenAxes, 'Position', [0, 0, 1, 0], 'FaceColor', 'g','EdgeColor','none');
            ylim(app.UIOxygenAxes, [0, 1]); % Normalize to percentage (0-1)
            hold(app.UIOxygenAxes, 'off');

            % Normalize oxygen data to [0, 1] for the bar
            normalizedOxygen = max(0,min(oxygenData,1)); % making oxygen be 0-100 range
            
            % loop through entire video length
            frameIdx = 1;
            while hasFrame(v) && frameIdx <= length(normalizedOxygen)
                im_frame.CData = readFrame(v);
                
                % Update oxygen bar
                currentLevel = normalizedOxygen(frameIdx); % Get current oxygen level
                oxygenBar.Position = [0, 0, 1, currentLevel]; % Adjust bar height
               
                % Update the heading pointer dynamically (example: vary angle)
                headingAngle = mod(headingAngle + 1, 360); % Example: Increment heading
                xPointer = [0, cosd(headingAngle)];
                yPointer = [0, sind(headingAngle)];
                compassPointer.XData = xPointer;
                compassPointer.YData = yPointer;
                
                % Update lamp color and text based on oxygen level
                if currentLevel < 0.5  % If oxygen level is below 50%
                    app.WARNINGLamp.Color = 'red'; % Set lamp color to red for warning
                    app.WARNINGLampLabel.Text = 'WARNING: LOW O2';
                else
                    app.WARNINGLamp.Color = 'green'; % Set lamp color to green if oxygen is safe
                    app.WARNINGLampLabel.Text = "O2 Nominal";
                end
                
                % increment frame index
                frameIdx = frameIdx + 1;
                
                % Draw video frame onto axes
                drawnow % this refreshes the UI

                % Get current frame (ie current data column index)
                % currentFrame = v.CurrentTime*v.FrameRate;
                % Display current value in UI axes
                %rectangle('Position',[1 2 5 6])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIOxygenAxes = uiaxes(app.UIFigure);
            title(app.UIOxygenAxes, 'Oxygen Level')
            zlabel(app.UIOxygenAxes, 'Z')
            app.UIOxygenAxes.XTick = [];
            app.UIOxygenAxes.YTick = [0 0.5 1];
            app.UIOxygenAxes.YTickLabel = {'0'; '50%'; '100%'};
            app.CompassAxes = uiaxes(app.UIFigure);
            title(app.CompassAxes, 'Title')
            xlabel(app.CompassAxes, 'X')
            ylabel(app.CompassAxes, 'Y')
            zlabel(app.CompassAxes, 'Z')
            app.PlaySimulationButton = uibutton(app.UIFigure, 'push');
            app.PlaySimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySimulationButtonPushed, true);
            app.PlaySimulationButton.Text = 'Play Simulation';
            app.WARNINGLampLabel = uilabel(app.UIFigure);
            app.WARNINGLampLabel.Text = 'WARNING';
            app.WARNINGLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HUD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end